classdef Davydov2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        LoadButton        matlab.ui.control.Button
        PlayButton        matlab.ui.control.Button
        PauseButton       matlab.ui.control.Button
        StopButton        matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
        UIAxes_2          matlab.ui.control.UIAxes
        Label             matlab.ui.control.Label
        HzSliderLabel     matlab.ui.control.Label
        HzSlider          matlab.ui.control.Slider
        HzSlider_2Label   matlab.ui.control.Label
        HzSlider_2        matlab.ui.control.Slider
        HzSlider_3Label   matlab.ui.control.Label
        HzSlider_3        matlab.ui.control.Slider
        HzSlider_4Label   matlab.ui.control.Label
        HzSlider_4        matlab.ui.control.Slider
        HzSlider_5Label   matlab.ui.control.Label
        HzSlider_5        matlab.ui.control.Slider
        HzSlider_6Label   matlab.ui.control.Label
        HzSlider_6        matlab.ui.control.Slider
        HzSlider_7Label   matlab.ui.control.Label
        HzSlider_7        matlab.ui.control.Slider
        HzSlider_8Label   matlab.ui.control.Label
        HzSlider_8        matlab.ui.control.Slider
        HzSlider_9Label   matlab.ui.control.Label
        HzSlider_9        matlab.ui.control.Slider
        HzSlider_10Label  matlab.ui.control.Label
        HzSlider_10       matlab.ui.control.Slider
    end

    
    properties (Access = public)
        equalizer Equalizer = Equalizer (64, 44100);
        deviceWriter audioDeviceWriter = audioDeviceWriter ('SampleRate',44100);
        fileReader dsp.AudioFileReader = dsp.AudioFileReader;
        
    end
    
    properties (Access = private)
        filename;
        stopFlag = false;
    end
    
    methods (Access = private)
        
        function PlotFreqResponce(app)
            [HdB, w] = GetFreqResponce(app.equalizer);
            plot(app.UIAxes2, w, HdB);
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PlotFreqResponce();
            drawnow;
        end

        % Value changed function: HzSlider
        function Gain1_Change(app, event)
            app.equalizer.gain(1) = app.gain1.Value;
            app.PlotFreqResponce();
            drawnow;
        end

        % Value changed function: HzSlider_2
        function Gain2_Change(app, event)
            app.equalizer.gain(2) = app.gain2.Value;
            app.PlotFreqResponce();
            drawnow;
        end

        % Value changed function: HzSlider_3
        function Gain3_Change(app, event)
            app.equalizer.gain(3) = app.gain3.Value;
            app.PlotFreqResponce();
            drawnow;
        end

        % Value changed function: HzSlider_4
        function Gain4_Change(app, event)
            app.equalizer.gain(4) = app.gain4.Value;
            app.PlotFreqResponce();
            drawnow;
            
        end

        % Value changed function: HzSlider_5
        function Gain5_Change(app, event)
            app.equalizer.gain(1) = app.gain1.Value;
            app.PlotFreqResponce();
            drawnow;
            
        end

        % Value changed function: HzSlider_6
        function Gain6_Change(app, event)
            app.equalizer.gain(6) = app.gain6.Value;
            app.PlotFreqResponce();
            drawnow;
        end

        % Value changed function: HzSlider_7
        function Gain7_Change(app, event)
            app.equalizer.gain(7) = app.gain7.Value;
            app.PlotFreqResponce();
            drawnow;
            
        end

        % Value changed function: HzSlider_8
        function Gain8_Change(app, event)
            app.equalizer.gain(8) = app.gain8.Value;
            app.PlotFreqResponce();
            drawnow;
        end

        % Value changed function: HzSlider_9
        function Gain9_Change(app, event)
            app.equalizer.gain(9) = app.gain9.Value;
            app.PlotFreqResponce();
            drawnow;
        end

        % Value changed function: HzSlider_10
        function Gain10_Change(app, event)
            app.equalizer.gain(10) = app.gain10.Value;
            app.PlotFreqResponce();
            drawnow;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.filename = uigetfile('*.mp3');
            app.Label.Text = app.filename;
            app.fileReader = dsp.AudioFileReader(app.filename,'PlayCount', inf,'SamplesPerFrame', 4096);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.Text = 'Stop';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Spectrum')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'FreqResponce')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.Label = uilabel(app.UIFigure);
            app.HzSliderLabel = uilabel(app.UIFigure);
            app.HzSliderLabel.Text = '62Hz';
            app.HzSlider = uislider(app.UIFigure);
            app.HzSlider.Limits = [-10 10];
            app.HzSlider.ValueChangedFcn = createCallbackFcn(app, @Gain1_Change, true);
            app.HzSlider_2Label = uilabel(app.UIFigure);
            app.HzSlider_2Label.Text = '125Hz';
            app.HzSlider_2 = uislider(app.UIFigure);
            app.HzSlider_2.Limits = [-10 10];
            app.HzSlider_2.ValueChangedFcn = createCallbackFcn(app, @Gain2_Change, true);
            app.HzSlider_3Label = uilabel(app.UIFigure);
            app.HzSlider_3Label.Text = '250Hz';
            app.HzSlider_3 = uislider(app.UIFigure);
            app.HzSlider_3.Limits = [-10 10];
            app.HzSlider_3.ValueChangedFcn = createCallbackFcn(app, @Gain3_Change, true);
            app.HzSlider_4Label = uilabel(app.UIFigure);
            app.HzSlider_4Label.Text = '500Hz';
            app.HzSlider_4 = uislider(app.UIFigure);
            app.HzSlider_4.Limits = [-10 10];
            app.HzSlider_4.ValueChangedFcn = createCallbackFcn(app, @Gain4_Change, true);
            app.HzSlider_5Label = uilabel(app.UIFigure);
            app.HzSlider_5Label.Text = '1000Hz';
            app.HzSlider_5 = uislider(app.UIFigure);
            app.HzSlider_5.Limits = [-10 10];
            app.HzSlider_5.ValueChangedFcn = createCallbackFcn(app, @Gain5_Change, true);
            app.HzSlider_6Label = uilabel(app.UIFigure);
            app.HzSlider_6Label.Text = '4000Hz';
            app.HzSlider_6 = uislider(app.UIFigure);
            app.HzSlider_6.Limits = [-10 10];
            app.HzSlider_6.ValueChangedFcn = createCallbackFcn(app, @Gain6_Change, true);
            app.HzSlider_7Label = uilabel(app.UIFigure);
            app.HzSlider_7Label.Text = '2000Hz';
            app.HzSlider_7 = uislider(app.UIFigure);
            app.HzSlider_7.Limits = [-10 10];
            app.HzSlider_7.ValueChangedFcn = createCallbackFcn(app, @Gain7_Change, true);
            app.HzSlider_8Label = uilabel(app.UIFigure);
            app.HzSlider_8Label.Text = '8000Hz';
            app.HzSlider_8 = uislider(app.UIFigure);
            app.HzSlider_8.Limits = [-10 10];
            app.HzSlider_8.ValueChangedFcn = createCallbackFcn(app, @Gain8_Change, true);
            app.HzSlider_9Label = uilabel(app.UIFigure);
            app.HzSlider_9Label.Text = '16000Hz';
            app.HzSlider_9 = uislider(app.UIFigure);
            app.HzSlider_9.Limits = [-10 10];
            app.HzSlider_9.ValueChangedFcn = createCallbackFcn(app, @Gain9_Change, true);
            app.HzSlider_10Label = uilabel(app.UIFigure);
            app.HzSlider_10Label.Text = '31Hz';
            app.HzSlider_10 = uislider(app.UIFigure);
            app.HzSlider_10.Limits = [-10 10];
            app.HzSlider_10.ValueChangedFcn = createCallbackFcn(app, @Gain10_Change, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Davydov2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end